classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTNCalculatorUIFigure  matlab.ui.Figure
        HTML                    matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = event.Data;
            
            % If JS on site has requested calculation
            if isfield(data, 'action') && strcmp(data.action, 'calculate')
                
                % 1. Extract inputs
                q_total = data.totalVehicles;
                q_hgv = data.hgvCount;
                t_obs = data.timeObserved;
                t_unit = data.timeUnit;
                v = data.velocity;
                v_unit = data.speedUnit;
                angle = data.angle;

                disp(q_total);
                disp(q_hgv);
                disp(t_obs);
                disp(t_unit);
                disp(v);
                disp(v_unit);
                disp(angle);
                
                % 2. Convert units for standardisation
                if strcmp(t_unit, 'mins')
                    time_hours = t_obs / 60;
                else
                    time_hours = t_obs;
                end
                
                if strcmp(v_unit, 'mph')
                    v_kph = v * 1.609;
                else
                    v_kph = v;
                end
                
                % 3. Calculate CRTN values
                vph = q_total / time_hours;
                pctHgv = (q_hgv / q_total) * 100;
                
                crtnCalc = 42.2 + 10 * log10(vph);
                corrSpeedHgv = 33 * log10(v_kph + 40 + (500 / v_kph)) + 10 * log10(1 + (5 * pctHgv / v_kph)) - 68.8;
                angCorr = 10 * log10(angle / 180);
                
                corrLevel = crtnCalc + corrSpeedHgv + angCorr;
                
                % 4. Package the results
                results = struct();
                results.action = 'updateOutputs';
                results.vph = vph;
                results.crtnCalc = crtnCalc;
                results.pctHgv = pctHgv;
                results.corrSpeedHgv = corrSpeedHgv;
                results.angCorr = angCorr;
                results.corrLevel = corrLevel;
                
                % 5. Send the results back to the HTML component
                disp('Calculations complete. Sending back to webpage...');
                    
                app.HTML.Data = results;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTNCalculatorUIFigure = uifigure('Visible', 'off');
            app.CRTNCalculatorUIFigure.Name = 'CRTN Calculator';
            app.HTML = uihtml(app.CRTNCalculatorUIFigure);
            app.HTML.HTMLSource = 'index.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.CRTNCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTNCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTNCalculatorUIFigure)
        end
    end
end