classdef compiled_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTNCalculatorUIFigure  matlab.ui.Figure
        HTML                    matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            htmlStrings = [
                "<!DOCTYPE html>"
                "<html lang='en'>"
                "    <head>"
                "        <meta charset='UTF-8'>"
                "        <meta name='viewport' content='width=device-width, initial-scale=1.0'>"
                "        <title>CRTN Calculator</title>"
                "        <style>"
                "@font-face {"
                "    font-family: Inter;"
                "    font-style: normal;"
                "    font-weight: 100 900;"
                "    font-display: swap;"
                "    src: url(data:application/octet-stream;base64,) format('woff2');"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
                "}"
                ""
                ":root {"
                "    --bg-1: #111111;"
                "    --bg-2: #1c1c1c;"
                "    --bg-3: #2a2a2a;"
                "    --fg-1: #ededed;"
                "    --fg-2: #a1a1aa;"
                "    --bd: #333333;"
                "    --e: #ff5252;"
                "    --w: #ffe652;"
                "    --s: #52ffb3;"
                "    --i: #2fbffd;"
                "}"
                ""
                "* {"
                "    font-family: 'Inter', sans-serif;"
                "    transition: .125s ease-in-out;"
                "    box-sizing: border-box;"
                "}"
                ""
                "body {"
                "    background: #000; "
                "    display: flex;"
                "    align-items: center;"
                "    justify-content: center;"
                "    min-height: 100vh;"
                "    margin: 0;"
                "    color: var(--fg-1);"
                "}"
                ""
                ".content-container {"
                "    display: flex;"
                "    width: 100%;"
                "    max-width: 56rem;"
                "    padding: 2rem;"
                "}"
                ""
                ".calculator-container {"
                "    border-radius: 1rem;"
                "    width: 100%;"
                "    padding: 2rem;"
                "}"
                ""
                ".header {"
                "    text-align: center;"
                "    margin-bottom: 2rem;"
                "}"
                ""
                ".header h1 {"
                "    margin: 0;"
                "    font-size: 2rem;"
                "}"
                ""
                ".header p {"
                "    color: var(--fg-2);"
                "    margin: .5rem 0 0 0;"
                "}"
                ""
                ".input-header {"
                "    color: var(--i);"
                "}"
                ""
                ".output-header {"
                "    color: var(--s);"
                "}"
                ""
                ".grid-container {"
                "    display: grid;"
                "    grid-template-columns: 1fr 1fr;"
                "    gap: 2rem;"
                "}"
                ""
                ".parameter-row {"
                "    display: flex;"
                "    justify-content: space-between;"
                "    align-items: center;"
                "    padding: .5rem 0;"
                "    border-bottom: 1px solid var(--bd);"
                "}"
                ""
                ".parameter-row label {"
                "    font-size: .9rem;"
                "    color: var(--fg-1);"
                "}"
                ""
                "input[type='number'], .output-box {"
                "    background: var(--bg-2);"
                "    border: 1px solid var(--bd);"
                "    color: var(--fg-1);"
                "    border-radius: .5rem;"
                "    padding: .5rem;"
                "    width: 5rem;"
                "    height: 2.2rem;"
                "    text-align: right;"
                "    font-weight: 600;"
                "}"
                ""
                "input[type='number']:hover {"
                "    border-color: var(--i);"
                "    background: var(--bg-3);"
                "}"
                ""
                "input[type='number']:focus {"
                "    outline: none;"
                "    border-color: var(--i);"
                "}"
                ""
                "input::-webkit-outer-spin-button,"
                "input::-webkit-inner-spin-button {"
                "    -webkit-appearance: none;"
                "    margin: 0;"
                "}"
                ""
                "input[type=number] {"
                "    -moz-appearance: textfield;"
                "}"
                ""
                ".output-col .output-box {"
                "    border: 1px solid var(--bd);"
                "    background: transparent;"
                "    display: inline-block;"
                "    cursor: pointer;"
                "}"
                ""
                ".output-col .output-box:hover {"
                "    border-color: var(--s);"
                "    background: var(--bg-2);"
                "}"
                ""
                ".parameter-row > div {"
                "    display: flex;"
                "    align-items: center;"
                "}"
                ""
                ".unit {"
                "    font-size: .8rem;"
                "    color: var(--fg-2);"
                "    margin-left: .5rem;"
                "    width: 2rem;"
                "    display: inline-block;"
                "}"
                ""
                ".input-col .unit {"
                "    width: 6rem; "
                "}"
                ".custom-toggle {"
                "    display: flex;"
                "    background: var(--bg-1);"
                "    border: 1px solid var(--bd);"
                "    border-radius: .5rem;"
                "    margin-left: .5rem;"
                "    overflow: hidden;"
                "    width: 6rem;"
                "    height: 2.2rem;"
                "}"
                ""
                ".toggle-input {"
                "    display: none;"
                "}"
                ""
                ".toggle-label {"
                "    flex: 1;"
                "    display: flex;"
                "    align-items: center;"
                "    justify-content: center;"
                "    font-size: .75rem;"
                "    color: var(--fg-2);"
                "    cursor: pointer;"
                "    user-select: none;"
                "    transition: .15s ease-in-out;"
                "}"
                ""
                ".toggle-label:hover {"
                "    background: var(--bg-3);"
                "    color: var(--fg-1);"
                "}"
                ""
                ".toggle-input:checked + .toggle-label {"
                "    background: var(--bg-3);"
                "    color: var(--fg-1);"
                "}"
                ""
                ".output-db {"
                "    color: var(--s);"
                "    font-size: 1.1rem;"
                "    font-weight: 700;"
                "}"
                ""
                "input.invalid {"
                "    border-color: var(--e);"
                "    background-color: color-mix(in srgb, var(--e) 15%, transparent);"
                "}"
                ""
                "input.invalid:focus, input.invalid:hover {"
                "    border-color: var(--e);"
                "    background-color: color-mix(in srgb, var(--e) 25%, transparent);"
                "}"
                ""
                ".debug-header {"
                "    color: var(--i);"
                "    margin: 2rem 0 .5rem;"
                "    font-size: 1rem;"
                "}"
                ""
                ".debug-console {"
                "    background: #000;"
                "    border: 1px solid var(--bd);"
                "    border-radius: .5rem;"
                "    padding: .75rem;"
                "    color: var(--s);"
                "    font-family: monospace;"
                "    font-size: .75rem;"
                "    height: 6rem;"
                "    overflow-y: auto;"
                "    white-space: pre-wrap;"
                "    word-wrap: break-word;"
                "}"
                ""
                "@media (max-width: 768px) {"
                "    .grid-container {"
                "        grid-template-columns: 1fr;"
                "    }"
                "}"
                "	</style>"
                "    </head>"
                "    <body>"
                "        <div class='content-container'>"
                "            <div class='calculator-container'>"
                "                <div class='header'>"
                "                    <h1>CRTN Calculator</h1>"
                "                    <p>Road Noise Assessment Tool</p>"
                "                </div>"
                "                <div class='grid-container'>"
                "                    <div class='input-col'>"
                "                        <h3 class='input-header'>Inputs</h3>"
                "                        <div class='parameter-row'>"
                "                            <label>Vehicles observed</label>"
                "                            <div><input type='number' value='0' id='totalVehicles'><span class='unit'></span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>HGVs observed</label>"
                "                            <div><input type='number' value='0' id='hgvCount'><span class='unit'></span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Time observed</label>"
                "                            <div>"
                "                                <input type='number' value='0' id='timeObserved'>"
                "                                <div class='custom-toggle'>"
                "                                    <input type='radio' id='unit-mins' name='timeUnit' value='mins' class='toggle-input' checked>"
                "                                    <label for='unit-mins' class='toggle-label'>mins</label>"
                "                                    <input type='radio' id='unit-hours' name='timeUnit' value='hours' class='toggle-input'>"
                "                                    <label for='unit-hours' class='toggle-label'>hrs</label>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Speed</label>"
                "                            <div>"
                "                                <input type='number' value='0' id='velocity'>"
                "                                <div class='custom-toggle'>"
                "                                    <input type='radio' id='unit-kph' name='speedUnit' value='kph' class='toggle-input' checked>"
                "                                    <label for='unit-kph' class='toggle-label'>km/h</label>"
                "                                    <input type='radio' id='unit-mph' name='speedUnit' value='mph' class='toggle-input'>"
                "                                    <label for='unit-mph' class='toggle-label'>mph</label>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Road angle</label>"
                "                            <div><input type='number' value='0' id='angle'><span class='unit'>deg</span></div>"
                "                        </div>"
                "                    </div>"
                "                    <div class='output-col'>"
                "                        <h3 class='output-header'>Calculations</h3>"
                "                        <div class='parameter-row'>"
                "                            <label>Vehicles per hour</label>"
                "                            <div><span class='output-box' id='vph'>0</span><span class='unit'></span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>CRTN calculation</label>"
                "                            <div><span class='output-box' id='crtnCalc'>0.00</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>HGV percentage</label>"
                "                            <div><span class='output-box' id='pctHgv'>0.00</span><span class='unit'>%</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Speed & HGV correction</label>"
                "                            <div><span class='output-box' id='corrSpeedHgv'>0.00</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Angular correction</label>"
                "                            <div><span class='output-box' id='angCorr'>0.00</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label class='output-db'>Corrected level</label>"
                "                            <div><span class='output-box output-db' id='corrLevel'>0.00</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                    </div>"
                "                </div>"
                "                <!-- <h3 class='debug-header'>Debug Console</h3>"
                "                <div id='debug-log' class='debug-console'>Waiting for user input...</div> -->"
                "            </div>"
                "        </div>"
                "<script>"
                "            const inputs = document.querySelectorAll('input[type=\'number\']');"
                "            const toggles = document.querySelectorAll('.toggle-input');"
                "            const outputs = document.querySelectorAll('.output-box');"
                ""
                "            let matlabObj = null;"
                ""
                "            function logDebug(message) {"
                "                return; // Disable logging"
                "            }"
                ""
                "            function connectToMatlab(htmlComponent) {"
                "                matlabObj = htmlComponent;"
                "                logDebug('SYS -> MATLAB Bridge Connected.');"
                ""
                "                matlabObj.addEventListener('DataChanged', function(event) {"
                "                    const results = event.Data;"
                "                    logDebug('IN <- Received from MATLAB: ' + JSON.stringify(results));"
                "                    "
                "                    if (results.action === 'updateOutputs') {"
                "                        try {"
                "                            document.getElementById('vph').innerText = Number(results.vph).toFixed(0);"
                "                            document.getElementById('crtnCalc').innerText = Number(results.crtnCalc).toFixed(2);"
                "                            document.getElementById('pctHgv').innerText = Number(results.pctHgv).toFixed(2);"
                "                            document.getElementById('corrSpeedHgv').innerText = Number(results.corrSpeedHgv).toFixed(2);"
                "                            document.getElementById('angCorr').innerText = Number(results.angCorr).toFixed(2);"
                "                            document.getElementById('corrLevel').innerText = Number(results.corrLevel).toFixed(2);"
                "                            logDebug('SYS -> UI Updated successfully.');"
                "                        } catch (err) {"
                "                            logDebug('ERROR -> Failed to update UI: ' + err.message);"
                "                            document.getElementById('corrLevel').innerText = 'ERR';"
                "                        }"
                "                    }"
                "                });"
                ""
                "                validateInputs();"
                "            }"
                ""
                "            function validateInputs() {"
                "                let isValid = true;"
                "                "
                "                const totalVehicles = parseFloat(document.getElementById('totalVehicles').value);"
                "                const hgvCount = parseFloat(document.getElementById('hgvCount').value);"
                "                const timeObserved = parseFloat(document.getElementById('timeObserved').value);"
                "                const velocity = parseFloat(document.getElementById('velocity').value);"
                "                const angle = parseFloat(document.getElementById('angle').value);"
                ""
                "                const setInvalid = (id) => {"
                "                    document.getElementById(id).classList.add('invalid');"
                "                    isValid = false;"
                "                };"
                "                const clearInvalid = (id) => {"
                "                    document.getElementById(id).classList.remove('invalid');"
                "                };"
                ""
                "                inputs.forEach(input => clearInvalid(input.id));"
                ""
                "                if (isNaN(totalVehicles) || totalVehicles < 0) setInvalid('totalVehicles');"
                "                if (isNaN(hgvCount) || hgvCount < 0 || hgvCount > totalVehicles) setInvalid('hgvCount');"
                "                if (isNaN(timeObserved) || timeObserved <= 0) setInvalid('timeObserved');"
                "                if (isNaN(velocity) || velocity <= 0) setInvalid('velocity');"
                "                if (isNaN(angle) || angle <= 0 || angle > 180) setInvalid('angle');"
                ""
                "                if (!isValid) {"
                "                    outputs.forEach(output => { output.innerText = '----'; });"
                "                } else {"
                "                    outputs.forEach(output => {"
                "                        if (output.innerText === '----') output.innerText = '0.00'; "
                "                    });"
                "                    "
                "                    if (matlabObj !== null) {"
                "                        const payload = {"
                "                            action: 'calculate',"
                "                            totalVehicles: totalVehicles,"
                "                            hgvCount: hgvCount,"
                "                            timeObserved: timeObserved,"
                "                            timeUnit: document.querySelector('input[name=\'timeUnit\']:checked').value,"
                "                            velocity: velocity,"
                "                            speedUnit: document.querySelector('input[name=\'speedUnit\']:checked').value,"
                "                            angle: angle"
                "                        };"
                "                        "
                "                        logDebug('OUT -> Sending to MATLAB...');"
                "                        matlabObj.Data = payload;"
                "                    }"
                "                }"
                "            }"
                ""
                "            inputs.forEach(input => { input.addEventListener('input', validateInputs); });"
                "            toggles.forEach(toggle => { toggle.addEventListener('change', validateInputs); });"
                ""
                "            // 5. THE MATLAB SETUP FUNCTION"
                "            function setup(htmlComponent) {"
                "                connectToMatlab(htmlComponent);"
                "            }"
                "        </script>"
                "    </body>"
                "</html>"
            ];
            app.HTML.HTMLSource = strjoin(htmlStrings, newline);
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = event.Data;
            
            % If JS on site has requested calculation
            if isfield(data, 'action') && strcmp(data.action, 'calculate')
                
                % 1. Extract inputs
                q_total = data.totalVehicles;
                q_hgv = data.hgvCount;
                t_obs = data.timeObserved;
                t_unit = data.timeUnit;
                v = data.velocity;
                v_unit = data.speedUnit;
                angle = data.angle;

                disp(q_total);
                disp(q_hgv);
                disp(t_obs);
                disp(t_unit);
                disp(v);
                disp(v_unit);
                disp(angle);
                
                % 2. Convert units for standardisation
                if strcmp(t_unit, 'mins')
                    time_hours = t_obs / 60;
                else
                    time_hours = t_obs;
                end
                
                if strcmp(v_unit, 'mph')
                    v_kph = v * 1.609;
                else
                    v_kph = v;
                end
                
                % 3. Calculate CRTN values
                vph = q_total / time_hours;
                pctHgv = (q_hgv / q_total) * 100;
                
                crtnCalc = 42.2 + 10 * log10(vph);
                corrSpeedHgv = 33 * log10(v_kph + 40 + (500 / v_kph)) + 10 * log10(1 + (5 * pctHgv / v_kph)) - 68.8;
                angCorr = 10 * log10(angle / 180);
                
                corrLevel = crtnCalc + corrSpeedHgv + angCorr;
                
                % 4. Package the results
                results = struct();
                results.action = 'updateOutputs';
                results.vph = vph;
                results.crtnCalc = crtnCalc;
                results.pctHgv = pctHgv;
                results.corrSpeedHgv = corrSpeedHgv;
                results.angCorr = angCorr;
                results.corrLevel = corrLevel;
                
                % 5. Send the results back to the HTML component
                disp('Calculations complete. Sending back to webpage...');
                    
                app.HTML.Data = results;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTNCalculatorUIFigure = uifigure('Visible', 'off');
            app.CRTNCalculatorUIFigure.Name = 'CRTN Calculator';
            app.HTML = uihtml(app.CRTNCalculatorUIFigure);
            app.HTML.HTMLSource = 'index.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.CRTNCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compiled_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTNCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTNCalculatorUIFigure)
        end
    end
end